classdef KeyEventGenGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        yGraph                        matlab.ui.control.UIAxes
        xGraph                        matlab.ui.control.UIAxes
        QHipGraph                     matlab.ui.control.UIAxes
        QKneeGraph                    matlab.ui.control.UIAxes
        GaitDefinitionTypeDropDownLabel  matlab.ui.control.Label
        GaitDefinitionTypeDropDown    matlab.ui.control.DropDown
        KeyEventsPhaseEditFieldLabel  matlab.ui.control.Label
        KeyEventsPhaseQKnee           matlab.ui.control.EditField
        KeyEventsYEditFieldLabel      matlab.ui.control.Label
        KeyEventsYQKnee               matlab.ui.control.EditField
        KeyEventsdYEditFieldLabel     matlab.ui.control.Label
        KeyEventsdYQKnee              matlab.ui.control.EditField
        KeyEventsPhaseQKnee_2Label    matlab.ui.control.Label
        KeyEventsPhaseQHip            matlab.ui.control.EditField
        KeyEventsYQKnee_2Label        matlab.ui.control.Label
        KeyEventsYQHip                matlab.ui.control.EditField
        KeyEventsdYQKnee_2Label       matlab.ui.control.Label
        KeyEventsdYQHip               matlab.ui.control.EditField
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Plot QKnee Graph  
            xQKnee=str2num(app.KeyEventsPhaseQKnee.Value);
            yQKnee=str2num(app.KeyEventsYQKnee.Value);
            plot(app.QKneeGraph,xQKnee,yQKnee,'x');
            
            %Plot QHip Graph
            xQHip=str2num(app.KeyEventsPhaseQHip.Value);
            yQHip=str2num(app.KeyEventsYQHip.Value);
            plot(app.QHipGraph,xQHip,yQHip,'x');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.yGraph = uiaxes(app.UIFigure);
            title(app.yGraph, 'Y');
            xlabel(app.yGraph, 'Phase (%)');
            ylabel(app.yGraph, 'Y (m)');
            app.yGraph.XLim = [0 100];
            app.yGraph.XLimMode = 'manual';
            app.xGraph = uiaxes(app.UIFigure);
            title(app.xGraph, 'X');
            xlabel(app.xGraph, 'Phase (%)');
            ylabel(app.xGraph, 'X (m)');
            app.xGraph.XLim = [0 100];
            app.xGraph.XLimMode = 'manual';
            app.QHipGraph = uiaxes(app.UIFigure);
            title(app.QHipGraph, 'Q Hip');
            xlabel(app.QHipGraph, 'Phase (%)');
            ylabel(app.QHipGraph, 'Q Hip (deg)');
            app.QHipGraph.XLim = [0 100];
            app.QHipGraph.XLimMode = 'manual';
            app.QHipGraph.YLim = [0 130];
            app.QHipGraph.YLimMode = 'manual';
            app.QKneeGraph = uiaxes(app.UIFigure);
            title(app.QKneeGraph, 'Q Knee');
            xlabel(app.QKneeGraph, 'Phase (%)');
            ylabel(app.QKneeGraph, 'Q Knee (deg)');
            app.QKneeGraph.XLim = [0 100];
            app.QKneeGraph.XLimMode = 'manual';
            app.QKneeGraph.YLim = [0 130];
            app.QKneeGraph.YLimMode = 'manual';
            app.GaitDefinitionTypeDropDownLabel = uilabel(app.UIFigure);
            app.GaitDefinitionTypeDropDownLabel.Text = 'Gait Definition Type';
            app.GaitDefinitionTypeDropDown = uidropdown(app.UIFigure);
            app.GaitDefinitionTypeDropDown.Items = {'Q Hip & Q Knee', 'Q Hip & X', 'Q Hip & Y', 'Q Knee & X', 'Q Knee & Y', 'X & Y'};
            app.GaitDefinitionTypeDropDown.Value = 'Q Hip & Q Knee';
            app.KeyEventsPhaseEditFieldLabel = uilabel(app.UIFigure);
            app.KeyEventsPhaseEditFieldLabel.Text = 'Key Events Phase';
            app.KeyEventsPhaseQKnee = uieditfield(app.UIFigure, 'text');
            app.KeyEventsPhaseQKnee.Value = '[0,20,40,60,80,100]';
            app.KeyEventsYEditFieldLabel = uilabel(app.UIFigure);
            app.KeyEventsYEditFieldLabel.Text = 'Key Events Y';
            app.KeyEventsYQKnee = uieditfield(app.UIFigure, 'text');
            app.KeyEventsYQKnee.Value = '[0,40,20,60,50,10]';
            app.KeyEventsdYEditFieldLabel = uilabel(app.UIFigure);
            app.KeyEventsdYEditFieldLabel.Text = 'Key Events dY';
            app.KeyEventsdYQKnee = uieditfield(app.UIFigure, 'text');
            app.KeyEventsdYQKnee.Value = '[0,1,0,-1,1,0]';
            app.KeyEventsPhaseQKnee_2Label = uilabel(app.UIFigure);
            app.KeyEventsPhaseQKnee_2Label.Text = 'Key Events Phase';
            app.KeyEventsPhaseQHip = uieditfield(app.UIFigure, 'text');
            app.KeyEventsPhaseQHip.Value = '[0,20,40,60,80,100]';
            app.KeyEventsYQKnee_2Label = uilabel(app.UIFigure);
            app.KeyEventsYQKnee_2Label.Text = 'Key Events Y';
            app.KeyEventsYQHip = uieditfield(app.UIFigure, 'text');
            app.KeyEventsYQHip.Value = '[0,40,20,60,50,10]';
            app.KeyEventsdYQKnee_2Label = uilabel(app.UIFigure);
            app.KeyEventsdYQKnee_2Label.Text = 'Key Events dY';
            app.KeyEventsdYQHip = uieditfield(app.UIFigure, 'text');
            app.KeyEventsdYQHip.Value = '[0,1,0,-1,1,0]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = KeyEventGenGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end